classdef Wound_DB_Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_5                  matlab.ui.control.UIAxes
        ShowDepth_meshButton_2    matlab.ui.control.Button
        SelectrecordListBoxLabel  matlab.ui.control.Label
        SelectrecordListBox       matlab.ui.control.ListBox
        Button_2                  matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes_7                  matlab.ui.control.UIAxes
        UIAxes_8                  matlab.ui.control.UIAxes
        UIAxes_9                  matlab.ui.control.UIAxes
        UIAxes_10                 matlab.ui.control.UIAxes
        UIAxes_11                 matlab.ui.control.UIAxes
        UIAxes_12                 matlab.ui.control.UIAxes
        AddmaskButton_2           matlab.ui.control.Button
        ShowDepth_meshButton      matlab.ui.control.Button
        ShowStereo_meshButton     matlab.ui.control.Button
        AddmaskButton_4           matlab.ui.control.Button
        AddmaskButton_3           matlab.ui.control.Button
        AddmaskButton_5           matlab.ui.control.Button
        AddmaskButton_6           matlab.ui.control.Button
        Button                    matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        % image data
        rphoto; % photo registered to thermal
        rthermal; % resulted thermal image
        photo; % photo
        mask; % expert mask
        thermal; % thermal image
        stereo_l; % stereo image from the left lens
        depth_cloud; % depth cloud - ToF camera
        depth_img; % depth image - ToF camera
        thermal_depth; % thermal - depth fusion
        thermal_photo; % thermal - photo fusion
        thermal_stereo; % thermal - stereo fusion
        stereo_mesh; % point cloud (Claron) with thermal texture
        depth_mesh; % point cloud (ToF) with thermal texture
        rmask; % expert mask in thermal image coordinate system
        no_data_img;
        
        % set paths
        main_path;
        path_patients;
        path_results;
        dname;
        data_path;
        
        % patient info
        records; % patient list
        patient; % patient ID
        date; % examination date
        scene; % scene to show
        
        % flags
        isrphoto; % mask flag
        ismask;
    end
    
    methods (Access = private)
        
        function show_image_data(app)
            %app.UIFigure.WindowState = 'maximize';
            imshow(app.photo,[],'Parent',app.UIAxes);
            if ~isempty(app.mask)
                imshow(app.mask,[],'Parent',app.UIAxes_2);
            else
                imshow(app.no_data_img,[],'Parent',app.UIAxes_2);
            end
            imshow(app.thermal,[],'Parent',app.UIAxes_3);
            imshow(app.stereo_l,[],'Parent',app.UIAxes_4);
            imshow(app.depth_img,[],'Parent',app.UIAxes_5);
            
            imshow(app.rphoto,[],'Parent',app.UIAxes_7);
            imshow(app.rthermal,[],'Parent',app.UIAxes_8);
            imshow(app.thermal_photo,[],'Parent',app.UIAxes_9);
            imshow(app.thermal_stereo,[],'Parent',app.UIAxes_10);
            imshow(app.thermal_depth,[],'Parent',app.UIAxes_11);
            if ~isempty(app.rmask)
                imshow(app.rmask,[],'Parent',app.UIAxes_12);
            else
                imshow(app.no_data_img,[],'Parent',app.UIAxes_12);
            end
        end
        
        function read_patient_data(app)
           
            imgs = clear_dr(dir(fullfile(app.main_path,app.path_patients,app.patient,app.date,'data',app.scene)));
            rimgs = clear_dr(dir(fullfile(app.main_path,app.path_results,app.patient,app.date,'results',app.scene)));
            
            try
                app.photo = imread(fullfile(imgs(1).folder,'photo.jpg'));
            catch
                app.photo = app.no_data_img;
            end
            % mask
            try
                mask = imread(fullfile(imgs(1).folder,'mask.png'));
                app.mask = rgb2gray(mask);
            catch
                app.mask = [];
                app.Button_2.Enable = 'off';
            end
            % thermal
            
            try
                app.thermal = imread(fullfile(imgs(1).folder,'thermal.png'));
            catch
                app.thermal = app.no_data_img;
            end
            
            try
                app.stereo_l = imread(fullfile(imgs(1).folder,'stereo_l.png'));
            catch
                app.stereo_l = app.no_data_img;
            end
            % stereo
            % depth
            try
                depth = clear_dr(dir(fullfile(imgs(1).folder,'depth_camera')));
                depth_data = load(fullfile(depth(1).folder,fullfile(depth(1).name)));
                app.depth_img = depth_data(433:576,:,:); % amplitude image
                app.depth_cloud = tof2cloud(depth_data);
            catch
                app.depth_cloud = [];
                app.depth_img = app.no_data_img;
            end
            
            %% read results
            try
                app.rphoto = imread(fullfile(rimgs(1).folder,'photo.png'));
            catch
                app.rphoto = app.no_data_img;
            end
            try
                app.rthermal = imread(fullfile(rimgs(1).folder,'thermal.png'));
            catch
                app.rthermal = app.no_data_img;
            end
            try
                app.thermal_depth = imread(fullfile(rimgs(1).folder,'thermal-depth.png'));
            catch
                app.thermal_depth = app.no_data_img;
            end
            try
                app.thermal_photo = imread(fullfile(rimgs(1).folder,'thermal-photo.png'));
            catch
                app.thermal_photo = app.no_data_img;
            end
            try
                app.thermal_stereo = imread(fullfile(rimgs(1).folder,'thermal-stereo.png'));
            catch
                app.thermal_stereo = app.no_data_img;
            end
            % read resulting point clouds
            try
                plyname1 = strcat(fullfile(rimgs(1).folder,'stereo-mesh.ply'));
                app.stereo_mesh = pcread(plyname1);
            catch
                app.stereo_mesh = [];
            end
            
            try
                plyname = strcat(fullfile(rimgs(1).folder,'depth-mesh.ply'));
                depth_mesh = pcread(plyname);
                loc = depth_mesh.Location;
                col = depth_mesh.Color;
                app.depth_mesh = depth_mesh;
            catch
                app.depth_mesh = [];
            end
            
             % read transformation data
            try
                fid = fopen(fullfile(rimgs(1).folder,'registration.json'));
                raw = fread(fid,inf);
                str = char(raw');
                fclose(fid);
                val = jsondecode(str);
                % transform
                app.rmask = transform_image(app.mask,val,size(app.thermal),0.25);
            catch
                app.rmask = [];
                app.AddmaskButton_2.Enable = 'off';
                app.AddmaskButton_3.Enable = 'off';
                app.AddmaskButton_4.Enable = 'off';
                app.AddmaskButton_5.Enable = 'off';
                app.AddmaskButton_6.Enable = 'off';
                app.Button.Enable = 'off';
            end
        end
        
        function no_data_dialog(app)
            d = dialog('Position',[600 300 250 150],'Name','No data');
            txt = uicontrol('Parent',d,...
                'Style','text',...
                'Position',[20 80 210 40],...
                'String','No data available');
            
            btn = uicontrol('Parent',d,...
                'Position',[85 20 70 25],...
                'String','Close',...
                'Callback','delete(gcf)');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximize';
            [main_path] = config;
            app.main_path = main_path;           
            app.path_patients = '\patients\';
            app.path_results = '\results';           
            % info - read description file;
            app.dname = '\data-description.csv';
            app.no_data_img = imread('no_data.png'); 
            
            ddata = readtable(fullfile(app.main_path,app.dname));
            dd = table2cell(ddata);
            records = dd(:,1);
            app.records = records;
            app.SelectrecordListBox.Items = records;
            
            % read the selected record
            data_path = char(records{1,1});
            app.data_path = data_path;
            f = strfind(data_path,'\');
            app.patient = data_path(f(1)+1:f(2)-1);
            app.date = data_path(f(2)+1:f(3)-1);
            app.scene = data_path(f(4)+1:end);
            
            read_patient_data(app);
            
            % set the UI Figure, name
            app.UIFigure.Name = ['Wound DB Patient: ' app.patient ', ' app.date ', ' app.scene];
            
            show_image_data(app);
            app.isrphoto = false;
            app.ismask = false;
            app.AddmaskButton_2.Visible = 'off';
            app.AddmaskButton_3.Visible = 'off';
            app.AddmaskButton_4.Visible = 'off';
            app.AddmaskButton_5.Visible = 'off';
            app.AddmaskButton_6.Visible = 'off';
        end

        % Button pushed function: ShowDepth_meshButton
        function ShowDepth_meshButtonPushed(app, event)
            if ~isempty(app.depth_mesh) 
                figure('NumberTitle','off','Name',['Depth_mesh']);
                pcshow(app.depth_mesh);
            else
                no_data_dialog(app);
            end
        end

        % Button pushed function: ShowStereo_meshButton
        function ShowStereo_meshButtonPushed(app, event)
            if ~isempty(app.stereo_mesh)
                figure('NumberTitle','off','Name',['Stereo_mesh']);
                pcshow(app.stereo_mesh);
            else
                no_data_dialog(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            close all;
        end

        % Button pushed function: AddmaskButton_2
        function AddmaskButton_2Pushed(app, event)
%             if ~app.isrphoto
                rmsk = app.rmask;
                rphoto = double(app.rphoto);
                mphoto = add_mask(rphoto,rmsk);
%                 imshow(mphoto,[],'Parent',app.UIAxes_7);
                figure('NumberTitle','off','Name',['Delineated photo']);
                imshow(mphoto,[]);
%                 app.isrphoto = true;
%             else
%                 rmsk = app.rmask;
%                 rphoto = double(app.rphoto);
%                 mphoto = add_mask(rphoto,rmsk);
%                 imshow(app.rphoto,[],'Parent',app.UIAxes_7);
%                 app.isrphoto = false;
%             end
            
%             
        end

        % Button pushed function: AddmaskButton_4
        function AddmaskButton_4Pushed(app, event)
            rmsk = app.rmask;
            rphoto = double(rgb2gray(app.rthermal));
            mthermal = add_mask(rphoto,rmsk);
            figure('NumberTitle','off','Name',['Delineated thermal']);
            imshow(mthermal,[]);
        end

        % Button pushed function: AddmaskButton_3
        function AddmaskButton_3Pushed(app, event)
            rmsk = app.rmask;
            rphoto = double(app.thermal_photo);
            mthermal_photo = add_mask(rphoto,rmsk);
            figure('NumberTitle','off','Name',['Delineated thermal_photo']);
            imshow(mthermal_photo,[]);
        end

        % Button pushed function: AddmaskButton_5
        function AddmaskButton_5Pushed(app, event)
            rmsk = app.rmask;
            rphoto = double(app.thermal_stereo);
            mthermal_stereo = add_mask(rphoto,rmsk);
            figure('NumberTitle','off','Name',['Delineated thermal_stereo']);
            imshow(mthermal_stereo,[]);
        end

        % Button pushed function: AddmaskButton_6
        function AddmaskButton_6Pushed(app, event)
            rmsk = app.rmask;
            rphoto = double(app.thermal_depth);
            mthermal_depth= add_mask(rphoto,rmsk);
            figure('NumberTitle','off','Name',['Delineated thermal_depth']);
            imshow(mthermal_depth,[]);
        end

        % Button pushed function: ShowDepth_meshButton_2
        function ShowDepth_meshButton_2Pushed(app, event)
            if ~isempty(app.depth_cloud)
                figure('NumberTitle','off','Name',['Depth_mesh']);
                pcshow(app.depth_cloud);
            else
                no_data_dialog(app);
            end
        end

        % Value changed function: SelectrecordListBox
        function SelectrecordListBoxValueChanged(app, event)
            app.Button_2.Enable = 'on';
            app.AddmaskButton_2.Enable = 'on';
            app.AddmaskButton_3.Enable = 'on';
            app.AddmaskButton_4.Enable = 'on';
            app.AddmaskButton_5.Enable = 'on';
            app.AddmaskButton_6.Enable = 'on';
            app.Button.Enable = 'on';
            value = app.SelectrecordListBox.Value; % patient info
            app.data_path = char(value);
            f = strfind(app.data_path,'\');
            app.patient = app.data_path(f(1)+1:f(2)-1);
            app.date = app.data_path(f(2)+1:f(3)-1);
            app.scene = app.data_path(f(4)+1:end);    
            w = waitbar(0.1,'Loading data...');
            read_patient_data(app);
            waitbar(0.5,w,'Loading data...');
            app.UIFigure.Name = ['Wound DB Patient: ' app.patient ', ' app.date ', ' app.scene];            
            show_image_data(app);
            waitbar(0.9,w,'Loading data...');
%             app.UIFigure.WindowState = 'maximize';
            delete(w);
%             1;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            msk = app.mask;
            photo = double(app.photo);
            mphoto= add_mask(photo,msk);
            figure('NumberTitle','off','Name',['Delineated photo']);
            imshow(mphoto,[]);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ~app.ismask
                rmsk = app.rmask;
                rphoto = double(app.rphoto);
                mphoto = add_mask(rphoto,rmsk);
                imshow(mphoto,[],'Parent',app.UIAxes_7);
                rmsk = app.rmask;
                rphoto = double(rgb2gray(app.rthermal));
                mthermal = add_mask(rphoto,rmsk);
                imshow(mthermal,[],'Parent',app.UIAxes_8);
                rmsk = app.rmask;
                rphoto = double(app.thermal_stereo);
                mthermal_stereo = add_mask(rphoto,rmsk);
                imshow(mthermal_stereo,[],'Parent',app.UIAxes_10);
                rmsk = app.rmask;
                rphoto = double(app.thermal_depth);
                mthermal_depth= add_mask(rphoto,rmsk);
                imshow(mthermal_depth,[],'Parent',app.UIAxes_11);
                rmsk = app.rmask;
                rphoto = double(app.thermal_photo);
                mthermal_photo = add_mask(rphoto,rmsk);
                imshow(mthermal_photo,[],'Parent',app.UIAxes_9);
                app.ismask = true;
            else
                rmsk = app.rmask;
                imshow(app.rphoto,[],'Parent',app.UIAxes_7);
                imshow(app.rthermal,[],'Parent',app.UIAxes_8);
                imshow(app.thermal_stereo,[],'Parent',app.UIAxes_10);
                imshow(app.thermal_depth,[],'Parent',app.UIAxes_11);
                imshow(app.thermal_photo,[],'Parent',app.UIAxes_9);
%                 app.isrphoto = false;
                app.ismask = false;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {985, 985};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1072, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Patient';
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Photo')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.LeftPanel);
            title(app.UIAxes_2, 'Mask')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_3 = uiaxes(app.LeftPanel);
            title(app.UIAxes_3, 'Thermal')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.YTickLabel = '';
            app.UIAxes_4 = uiaxes(app.LeftPanel);
            title(app.UIAxes_4, 'Stereo\_l')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.YTickLabel = '';
            app.UIAxes_5 = uiaxes(app.LeftPanel);
            title(app.UIAxes_5, 'Depth amplitude')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.XTickLabel = '';
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.YTickLabel = '';
            app.ShowDepth_meshButton_2 = uibutton(app.LeftPanel, 'push');
            app.ShowDepth_meshButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowDepth_meshButton_2Pushed, true);
            app.ShowDepth_meshButton_2.Text = 'Show Depth_mesh';
            app.SelectrecordListBoxLabel = uilabel(app.LeftPanel);
            app.SelectrecordListBoxLabel.Text = 'Select record';
            app.SelectrecordListBox = uilistbox(app.LeftPanel);
            app.SelectrecordListBox.ValueChangedFcn = createCallbackFcn(app, @SelectrecordListBoxValueChanged, true);
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Results';
            app.UIAxes_7 = uiaxes(app.RightPanel);
            title(app.UIAxes_7, 'Photo')
            xlabel(app.UIAxes_7, '')
            ylabel(app.UIAxes_7, '')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.XTickLabel = '';
            app.UIAxes_7.YTick = [];
            app.UIAxes_7.YTickLabel = '';
            app.UIAxes_8 = uiaxes(app.RightPanel);
            title(app.UIAxes_8, 'Thermal')
            xlabel(app.UIAxes_8, '')
            ylabel(app.UIAxes_8, '')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.XTickLabel = '';
            app.UIAxes_8.YTick = [];
            app.UIAxes_8.YTickLabel = '';
            app.UIAxes_9 = uiaxes(app.RightPanel);
            title(app.UIAxes_9, 'Thermal\_photo')
            xlabel(app.UIAxes_9, '')
            ylabel(app.UIAxes_9, '')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.XTickLabel = '';
            app.UIAxes_9.YTick = [];
            app.UIAxes_9.YTickLabel = '';
            app.UIAxes_10 = uiaxes(app.RightPanel);
            title(app.UIAxes_10, 'Thermal\_stereo')
            xlabel(app.UIAxes_10, '')
            ylabel(app.UIAxes_10, '')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.XTickLabel = '';
            app.UIAxes_10.YTick = [];
            app.UIAxes_10.YTickLabel = '';
            app.UIAxes_11 = uiaxes(app.RightPanel);
            title(app.UIAxes_11, 'Thermal\_depth')
            xlabel(app.UIAxes_11, '')
            ylabel(app.UIAxes_11, '')
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.XTickLabel = '';
            app.UIAxes_11.YTick = [];
            app.UIAxes_11.YTickLabel = '';
            app.UIAxes_12 = uiaxes(app.RightPanel);
            title(app.UIAxes_12, 'Mask')
            xlabel(app.UIAxes_12, '')
            ylabel(app.UIAxes_12, '')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.XTickLabel = '';
            app.UIAxes_12.YTick = [];
            app.UIAxes_12.YTickLabel = '';
            app.AddmaskButton_2 = uibutton(app.RightPanel, 'push');
            app.AddmaskButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddmaskButton_2Pushed, true);
            app.AddmaskButton_2.Text = 'Add mask';
            app.ShowDepth_meshButton = uibutton(app.RightPanel, 'push');
            app.ShowDepth_meshButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDepth_meshButtonPushed, true);
            app.ShowDepth_meshButton.Text = 'Show Depth_mesh';
            app.ShowStereo_meshButton = uibutton(app.RightPanel, 'push');
            app.ShowStereo_meshButton.ButtonPushedFcn = createCallbackFcn(app, @ShowStereo_meshButtonPushed, true);
            app.ShowStereo_meshButton.Text = 'Show Stereo_mesh';
            app.AddmaskButton_4 = uibutton(app.RightPanel, 'push');
            app.AddmaskButton_4.ButtonPushedFcn = createCallbackFcn(app, @AddmaskButton_4Pushed, true);
            app.AddmaskButton_4.Text = 'Add mask';
            app.AddmaskButton_3 = uibutton(app.RightPanel, 'push');
            app.AddmaskButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddmaskButton_3Pushed, true);
            app.AddmaskButton_3.Text = 'Add mask';
            app.AddmaskButton_5 = uibutton(app.RightPanel, 'push');
            app.AddmaskButton_5.ButtonPushedFcn = createCallbackFcn(app, @AddmaskButton_5Pushed, true);
            app.AddmaskButton_5.Text = 'Add mask';
            app.AddmaskButton_6 = uibutton(app.RightPanel, 'push');
            app.AddmaskButton_6.ButtonPushedFcn = createCallbackFcn(app, @AddmaskButton_6Pushed, true);
            app.AddmaskButton_6.Text = 'Add mask';
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wound_DB_Viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end